classdef make_nets_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkGeneratorUIFigure        matlab.ui.Figure
        MakemynetworksButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BasicInfoTab                    matlab.ui.container.Tab
        NetworkTypeDropDownLabel        matlab.ui.control.Label
        NetworkTypeDropDown             matlab.ui.control.DropDown
        NumberofNetworksEditFieldLabel  matlab.ui.control.Label
        NumberofNetworksEditField       matlab.ui.control.NumericEditField
        RVESideLengthumEditFieldLabel   matlab.ui.control.Label
        RVESideLengthumEditField        matlab.ui.control.NumericEditField
        FiberRadiusnmEditFieldLabel     matlab.ui.control.Label
        FiberRadiusnmEditField          matlab.ui.control.NumericEditField
        VolumeFractionEditFieldLabel    matlab.ui.control.Label
        VolumeFractionEditField         matlab.ui.control.NumericEditField
        NetworkAlignmentTab             matlab.ui.container.Tab
        Isotropic033Label               matlab.ui.control.Label
        HighlyAligned085Label           matlab.ui.control.Label
        DirectionofAlignmentDropDownLabel  matlab.ui.control.Label
        DirectionofAlignmentDropDown    matlab.ui.control.DropDown
        NetworkAlignmentSliderLabel     matlab.ui.control.Label
        NetworkAlignmentSlider          matlab.ui.control.Slider
        NetworkRotationTab              matlab.ui.container.Tab
        AxisofRotationDropDownLabel     matlab.ui.control.Label
        AxisofRotationDropDown          matlab.ui.control.DropDown
        NetworkRotationdegreesSliderLabel  matlab.ui.control.Label
        NetworkRotationdegreesSlider    matlab.ui.control.Slider
        OutputOptionsTab                matlab.ui.container.Tab
        BrowseButton                    matlab.ui.control.Button
        SaveDirectoryLabel              matlab.ui.control.Label
        DirectoryLocationLabel          matlab.ui.control.Label
        NetworkFileNameEditFieldLabel   matlab.ui.control.Label
        NetworkFileNameEditField        matlab.ui.control.EditField
        CalculateNetworkStatisticswhenFinishedCheckBox  matlab.ui.control.CheckBox
        NetworkFileTypeDropDownLabel    matlab.ui.control.Label
        NetworkFileTypeDropDown         matlab.ui.control.DropDown
        WriteFilewithConvertValueCheckBox  matlab.ui.control.CheckBox
        SaveNetworkImagesCheckBox       matlab.ui.control.CheckBox
    end

    
    methods (Access = public)
        
        function prog_bar(app)
            global prog_box;
            prog_box = uiprogressdlg(app.NetworkGeneratorUIFigure,'Title','Please Wait',...
                'Message','Beginning Network Generation. . .');
            pause(5)
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global net_type net_align align_dir net_rot rot_axis netfile_type
            net_type = app.NetworkTypeDropDown.Value; % Set default value for network type drop down
            net_align = app.NetworkAlignmentSlider.Value; % Set default value for alignment slider
            align_dir = app.DirectionofAlignmentDropDown.Value; % Set default value for dir of align
            net_rot = app.NetworkRotationdegreesSlider.Value; % Set default value for rotation slider
            rot_axis = app.AxisofRotationDropDown.Value; % Set default value for rotation axis
            netfile_type = app.NetworkFileTypeDropDown.Value;
        end

        % Value changed function: NetworkFileNameEditField
        function NetworkFileNameEditFieldValueChanged(app, event)
            global net_name;
            net_name = app.NetworkFileNameEditField.Value; % Network file prefix
            
        end

        % Value changed function: NetworkTypeDropDown
        function NetworkTypeDropDownValueChanged(app, event)
            global net_type;
            net_type = app.NetworkTypeDropDown.Value; % Type of network to make
            
        end

        % Value changed function: NumberofNetworksEditField
        function NumberofNetworksEditFieldValueChanged(app, event)
            global num_nets;
            num_nets = app.NumberofNetworksEditField.Value; % Number of networks to make
            
        end

        % Value changed function: RVESideLengthumEditField
        function RVESideLengthumEditFieldValueChanged(app, event)
            global rve_len;
            rve_len = app.RVESideLengthumEditField.Value; % RVE length to dimensionalize networks
            
        end

        % Value changed function: VolumeFractionEditField
        function VolumeFractionEditFieldValueChanged(app, event)
            global target_vol_fract;
            target_vol_fract = app.VolumeFractionEditField.Value; % Target volume fraction for networks
            
        end

        % Value changed function: DirectionofAlignmentDropDown
        function DirectionofAlignmentDropDownValueChanged(app, event)
            global align_dir;
            align_dir = app.DirectionofAlignmentDropDown.Value; % Direction to align networks
            
        end

        % Value changed function: NetworkAlignmentSlider
        function NetworkAlignmentSliderValueChanged(app, event)
            global net_align;
            net_align = app.NetworkAlignmentSlider.Value; % Degree of network alignment
            
        end

        % Value changed function: NetworkRotationdegreesSlider
        function NetworkRotationdegreesSliderValueChanged(app, event)
            global net_rot;
            net_rot = app.NetworkRotationdegreesSlider.Value; % Angle of rotation for networks
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global save_path;
            save_path = uigetdir(pwd,'Please select the directory where you want to save new networks');
            if save_path == 0
                save_path = pwd;
            end
            app.NetworkGeneratorUIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.NetworkGeneratorUIFigure.Visible = 'on';    
            app.DirectoryLocationLabel.Text=save_path;  % Sets the label text to be the selected path

        end

        % Value changed function: FiberRadiusnmEditField
        function FiberRadiusnmEditFieldValueChanged(app, event)
            global fiber_rad;
            fiber_rad = app.FiberRadiusnmEditField.Value; % Fiber radius
            
        end

        % Button pushed function: MakemynetworksButton
        function MakemynetworksButtonPushed(app, event)
            global prog_box net_name net_type num_nets rve_len target_vol_fract align_dir net_align net_rot rot_axis save_path fiber_rad write_convert save_image netfile_type net_stats;
            prog_bar(app)
            create_volfract(net_name, net_type, num_nets, rve_len, target_vol_fract, align_dir, net_align, net_rot, rot_axis, save_path, fiber_rad, write_convert, save_image, netfile_type, net_stats);

        end

        % Value changed function: AxisofRotationDropDown
        function AxisofRotationDropDownValueChanged(app, event)
            global rot_axis;
            rot_axis = app.AxisofRotationDropDown.Value;
            
        end

        % Value changed function: WriteFilewithConvertValueCheckBox
        function WriteFilewithConvertValueCheckBoxValueChanged(app, event)
            global write_convert;
            write_convert = app.WriteFilewithConvertValueCheckBox.Value;
            
        end

        % Value changed function: SaveNetworkImagesCheckBox
        function SaveNetworkImagesCheckBoxValueChanged(app, event)
            global save_image;
            save_image = app.SaveNetworkImagesCheckBox.Value;
            
        end

        % Value changed function: NetworkFileTypeDropDown
        function NetworkFileTypeDropDownValueChanged(app, event)
            global netfile_type;
            netfile_type = app.NetworkFileTypeDropDown.Value;
            
        end

        % Value changed function: 
        % CalculateNetworkStatisticswhenFinishedCheckBox
        function CalculateNetworkStatisticswhenFinishedCheckBoxValueChanged(app, event)
            global net_stats;
            net_stats = app.CalculateNetworkStatisticswhenFinishedCheckBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkGeneratorUIFigure = uifigure;
            app.NetworkGeneratorUIFigure.Name = 'Network Generator';
            app.MakemynetworksButton = uibutton(app.NetworkGeneratorUIFigure, 'push');
            app.MakemynetworksButton.ButtonPushedFcn = createCallbackFcn(app, @MakemynetworksButtonPushed, true);
            app.MakemynetworksButton.Text = 'Make my networks!';
            app.TabGroup = uitabgroup(app.NetworkGeneratorUIFigure);
            app.BasicInfoTab = uitab(app.TabGroup);
            app.BasicInfoTab.Title = 'Basic Info';
            app.NetworkTypeDropDownLabel = uilabel(app.BasicInfoTab);
            app.NetworkTypeDropDownLabel.Text = 'Network Type';
            app.NetworkTypeDropDown = uidropdown(app.BasicInfoTab);
            app.NetworkTypeDropDown.Items = {'Delaunay', 'Voronoi'};
            app.NetworkTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkTypeDropDownValueChanged, true);
            app.NetworkTypeDropDown.Value = 'Delaunay';
            app.NumberofNetworksEditFieldLabel = uilabel(app.BasicInfoTab);
            app.NumberofNetworksEditFieldLabel.Text = 'Number of Networks';
            app.NumberofNetworksEditField = uieditfield(app.BasicInfoTab, 'numeric');
            app.NumberofNetworksEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNetworksEditFieldValueChanged, true);
            app.RVESideLengthumEditFieldLabel = uilabel(app.BasicInfoTab);
            app.RVESideLengthumEditFieldLabel.Text = 'RVE Side Length (um)';
            app.RVESideLengthumEditField = uieditfield(app.BasicInfoTab, 'numeric');
            app.RVESideLengthumEditField.ValueChangedFcn = createCallbackFcn(app, @RVESideLengthumEditFieldValueChanged, true);
            app.FiberRadiusnmEditFieldLabel = uilabel(app.BasicInfoTab);
            app.FiberRadiusnmEditFieldLabel.Text = 'Fiber Radius (nm)';
            app.FiberRadiusnmEditField = uieditfield(app.BasicInfoTab, 'numeric');
            app.FiberRadiusnmEditField.ValueChangedFcn = createCallbackFcn(app, @FiberRadiusnmEditFieldValueChanged, true);
            app.VolumeFractionEditFieldLabel = uilabel(app.BasicInfoTab);
            app.VolumeFractionEditFieldLabel.Text = 'Volume Fraction';
            app.VolumeFractionEditField = uieditfield(app.BasicInfoTab, 'numeric');
            app.VolumeFractionEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeFractionEditFieldValueChanged, true);
            app.NetworkAlignmentTab = uitab(app.TabGroup);
            app.NetworkAlignmentTab.Title = 'Network Alignment';
            app.Isotropic033Label = uilabel(app.NetworkAlignmentTab);
            app.Isotropic033Label.Text = 'Isotropic (0.33)';
            app.HighlyAligned085Label = uilabel(app.NetworkAlignmentTab);
            app.HighlyAligned085Label.Text = 'Highly Aligned (0.85)';
            app.DirectionofAlignmentDropDownLabel = uilabel(app.NetworkAlignmentTab);
            app.DirectionofAlignmentDropDownLabel.Text = 'Direction of Alignment';
            app.DirectionofAlignmentDropDown = uidropdown(app.NetworkAlignmentTab);
            app.DirectionofAlignmentDropDown.Items = {'X', 'Y', 'Z'};
            app.DirectionofAlignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionofAlignmentDropDownValueChanged, true);
            app.DirectionofAlignmentDropDown.Value = 'X';
            app.NetworkAlignmentSliderLabel = uilabel(app.NetworkAlignmentTab);
            app.NetworkAlignmentSliderLabel.Text = 'Network Alignment';
            app.NetworkAlignmentSlider = uislider(app.NetworkAlignmentTab);
            app.NetworkAlignmentSlider.Limits = [0.33 0.85];
            app.NetworkAlignmentSlider.MajorTicks = [0.33 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85];
            app.NetworkAlignmentSlider.ValueChangedFcn = createCallbackFcn(app, @NetworkAlignmentSliderValueChanged, true);
            app.NetworkAlignmentSlider.Value = 0.33;
            app.NetworkRotationTab = uitab(app.TabGroup);
            app.NetworkRotationTab.Title = 'Network Rotation';
            app.AxisofRotationDropDownLabel = uilabel(app.NetworkRotationTab);
            app.AxisofRotationDropDownLabel.Text = 'Axis of Rotation';
            app.AxisofRotationDropDown = uidropdown(app.NetworkRotationTab);
            app.AxisofRotationDropDown.Items = {'X', 'Y', 'Z'};
            app.AxisofRotationDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisofRotationDropDownValueChanged, true);
            app.AxisofRotationDropDown.Value = 'X';
            app.NetworkRotationdegreesSliderLabel = uilabel(app.NetworkRotationTab);
            app.NetworkRotationdegreesSliderLabel.Text = 'Network Rotation (degrees)';
            app.NetworkRotationdegreesSlider = uislider(app.NetworkRotationTab);
            app.NetworkRotationdegreesSlider.Limits = [0 90];
            app.NetworkRotationdegreesSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.NetworkRotationdegreesSlider.ValueChangedFcn = createCallbackFcn(app, @NetworkRotationdegreesSliderValueChanged, true);
            app.OutputOptionsTab = uitab(app.TabGroup);
            app.OutputOptionsTab.Title = 'Output Options';
            app.BrowseButton = uibutton(app.OutputOptionsTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SaveDirectoryLabel = uilabel(app.OutputOptionsTab);
            app.SaveDirectoryLabel.Text = 'Save Directory';
            app.DirectoryLocationLabel = uilabel(app.OutputOptionsTab);
            app.DirectoryLocationLabel.Text = 'Directory Location';
            app.NetworkFileNameEditFieldLabel = uilabel(app.OutputOptionsTab);
            app.NetworkFileNameEditFieldLabel.Text = 'Network File Name ';
            app.NetworkFileNameEditField = uieditfield(app.OutputOptionsTab, 'text');
            app.NetworkFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @NetworkFileNameEditFieldValueChanged, true);
            app.CalculateNetworkStatisticswhenFinishedCheckBox = uicheckbox(app.OutputOptionsTab);
            app.CalculateNetworkStatisticswhenFinishedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateNetworkStatisticswhenFinishedCheckBoxValueChanged, true);
            app.CalculateNetworkStatisticswhenFinishedCheckBox.Text = 'Calculate Network Statistics when Finished';
            app.NetworkFileTypeDropDownLabel = uilabel(app.OutputOptionsTab);
            app.NetworkFileTypeDropDownLabel.Text = 'Network File Type';
            app.NetworkFileTypeDropDown = uidropdown(app.OutputOptionsTab);
            app.NetworkFileTypeDropDown.Items = {'Text File', '.mat File'};
            app.NetworkFileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkFileTypeDropDownValueChanged, true);
            app.NetworkFileTypeDropDown.Value = 'Text File';
            app.WriteFilewithConvertValueCheckBox = uicheckbox(app.OutputOptionsTab);
            app.WriteFilewithConvertValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @WriteFilewithConvertValueCheckBoxValueChanged, true);
            app.WriteFilewithConvertValueCheckBox.Text = 'Write File with Convert Value';
            app.SaveNetworkImagesCheckBox = uicheckbox(app.OutputOptionsTab);
            app.SaveNetworkImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveNetworkImagesCheckBoxValueChanged, true);
            app.SaveNetworkImagesCheckBox.Text = 'Save Network Images';
        end
    end

    methods (Access = public)

        % Construct app
        function app = make_nets_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkGeneratorUIFigure)
        end
    end
end